classdef binarization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PickimageDropDownLabel         matlab.ui.control.Label
        PickimageDropDown              matlab.ui.control.DropDown
        BinarizeButton                 matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        Histogram                      matlab.ui.control.UIAxes
        ThresholdValue                 matlab.ui.control.Slider
        BinarizationthresholdDropDownLabel  matlab.ui.control.Label
        BinarizationthresholdDropDown  matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        ColorImage                     matlab.ui.control.Image
        GreyImage                      matlab.ui.control.Image
        BinaryImage                    matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cim % Color image
        bim % Binary image
        path % Path to image
        gim % Grey image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.path = "TestPictures/"+app.PickimageDropDown.Value;
            app.cim = double(imread(app.path))/255;
            app.gim = ao_rgb2gray(app.cim);
            app.ColorImage.ImageSource = app.cim;
            app.GreyImage.ImageSource = repmat(app.gim, [1 1 3]);
            [count, binLocations] = imhist(app.gim);
            bar(app.Histogram, binLocations, count, 'BarWidth', 1);
            % histogram(app.Histogram, binLocations, count, "BarWidth", 1);
        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            thresh = 0;
            adap = false;
            switch (app.BinarizationthresholdDropDown.Value)    
                case "Otsu's method"
                    thresh = ao_otsuthresh(app.gim);
                case "Manual"
                    thresh = app.ThresholdValue.Value;
                case "Adaptive"
                    thresh = 0; ws = 30; C = 0.05; adap = true;
                    im_median = ao_medianfilt(app.gim, ws);
                    app.gim = im_median - app.gim - C;
                    
            end
            app.bim = ao_imbinarize(app.gim, thresh);
            if ~adap
                tmp_bim = logical(app.bim);
                tmp_bim = ~tmp_bim;
                app.bim = double(tmp_bim);
            end
            app.BinaryImage.ImageSource = repmat(app.bim, [1 1 3]);
            if adap
                app.gim = ao_rgb2gray(app.cim);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PickimageDropDownLabel = uilabel(app.LeftPanel);
            app.PickimageDropDownLabel.Text = 'Pick image';
            app.PickimageDropDown = uidropdown(app.LeftPanel);
            app.PickimageDropDown.Items = {'kaczki.jpg', 'kapibary.jpg', 'opera.jpg', 'ptaki2.jpg', 'ptaki.jpg', 'zubr.jpg', ''};
            app.PickimageDropDown.Value = 'kaczki.jpg';
            app.BinarizeButton = uibutton(app.LeftPanel, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Text = 'Binarize';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.Histogram = uiaxes(app.LeftPanel);
            title(app.Histogram, 'Image histogram')
            xlabel(app.Histogram, 'Pixel value')
            ylabel(app.Histogram, 'Amount')
            app.Histogram.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Histogram.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.Histogram.YTick = [];
            app.Histogram.YTickLabel = '';
            app.ThresholdValue = uislider(app.LeftPanel);
            app.ThresholdValue.Limits = [0 1];
            app.BinarizationthresholdDropDownLabel = uilabel(app.LeftPanel);
            app.BinarizationthresholdDropDownLabel.Text = 'Binarization threshold';
            app.BinarizationthresholdDropDown = uidropdown(app.LeftPanel);
            app.BinarizationthresholdDropDown.Items = {'Otsu''s method', 'Manual', 'Adaptive'};
            app.BinarizationthresholdDropDown.Value = 'Otsu''s method';
            app.RightPanel = uipanel(app.GridLayout);
            app.ColorImage = uiimage(app.RightPanel);
            app.GreyImage = uiimage(app.RightPanel);
            app.BinaryImage = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binarization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end